classdef openep_workflow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OpenEPWorkflowEditorUIFigure  matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        LoadWorkflowMenu              matlab.ui.container.Menu
        SaveWorkflowMenu              matlab.ui.container.Menu
        ShareMenu                     matlab.ui.container.Menu
        ZenodoMenu                    matlab.ui.container.Menu
        TwitterMenu                   matlab.ui.container.Menu
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        Image_2                       matlab.ui.control.Image
        WorkflowsLabel                matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        UITable                       matlab.ui.control.Table
        RightPanel                    matlab.ui.container.Panel
        Panel                         matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        BlockPanel                    matlab.ui.container.Panel
        GridLayout3                   matlab.ui.container.GridLayout
        Button_2                      matlab.ui.control.Button
        Button                        matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = public)
        defDir % absolute path to the folder containing the function definitions
        bbObjects % the available objects
        wfObjects % the objects that are used in the workflow
        selectedTableRow

        % these properties are for storing handles for blocks that are
        % added and can subsequently be removed from the UI (we always have
        % the first default one stored in BlockLayout and GridLayout3
        % above)
        AdditionalBlockPanels  
        AdditionalGridLayouts
        AdditionalAddButtons
        AdditionalRemoveButtons
    end

    methods (Access = private)

        function readDefFolder(app)

            allXmlFiles = nameFiles(app.defDir, 'extension', 'xml');

            for i = 1:numel(allXmlFiles)
                app.bbObjects{i} = OpenEpBuildingBlock([app.defDir filesep() allXmlFiles{i}]);
                names{i,1} = app.bbObjects{i}.name;
                authors{i,1} = app.bbObjects{i}.author;
            end

            app.UITable.Data = table(names, authors);

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.defDir = '/Users/steven/Dropbox/Matlab_Code/workingdir/openep-workflows/def';
            readDefFolder(app)
        end

        % Button pushed function: Button
        function AddButtonPushed(app, event)

            % % % IN THIS SECTION WE GET THE INDEXES AND NUMBER OF INPUTS

            % Check that a table row is selected; and if not return and do 
            % nothing except inform the usesr
            if isempty(app.selectedTableRow)
                warning('OPENEP/OPENEP_WORKFLOW: A table row must be selected first')
                return
            end

            % Find out which row in the workflow editor we are working
            % in, this is the index of the workflow block in the list
            hP = get(get(event.Source, 'parent'),'parent');
            workFlowRowIndex = hP.Layout.Row;

            % Find out which row in the table (list of available building
            % blocks) was selected, which also indexes into     app.bbObjects
            tableRowIndex = app.selectedTableRow;

            % Count the number of inputs that the building block has
            numInputs = numel(app.bbObjects{tableRowIndex}.inputs);

            % % % IN THIS SECTION WE STORE A COPY OF THE OPENEPBUILDINGBLOCK in app.wfObjects. 
            
            % We consider that the OPENEPBUILDINGBLOCK objects in 
            % app.bbObjects are immutable; whereas those in app.wfObjects 
            % are mutable (can be edited, for example, by the specification 
            % of inputs or by handling sequences defining previous object 
            % relationships. In this beta code the objects are all mutable 
            % though ... TODO: set up access rights/mutability
            app.wfObjects{workFlowRowIndex} = app.bbObjects{tableRowIndex};

            % configure the previous relationship of this block, if
            % applicable
            if workFlowRowIndex == 1
                app.wfObjects{workFlowRowIndex}.hasPrevious = false;
            else
                app.wfObjects{workFlowRowIndex}.hasPrevious = true;
                app.wfObjects{workFlowRowIndex}.previousBuildingBlock = app.wfObjects{workFlowRowIndex - 1};
            end

            % % % IN THIS SECTION WE UPDATE THE FORMATTING OF THE BLOCK

            % Update the title and colour of the panel
            set(hP, 'title', app.bbObjects{tableRowIndex}.name);
            set(hP, 'backgroundcolor', [0.8 0.89 0.95]);
           
            % Add enough rows to the grid view to handle all the avialable
            % input arguments
            hG = get(event.Source, 'parent');
            RowHeight = {'fit', 'fit'};
            for i = 1:numInputs
                RowHeight{end+1} = 'fit'; %#ok<*AGROW>
            end
            set(hG, 'RowHeight', RowHeight);

            % Loop through the available input arguments and set up the 
            % required input UI elements
            for i = 1:numInputs
                hL{i} = uilabel(hG);
                hL{i}.Layout.Row = i+2;
                hL{i}.Layout.Column = 1;
                hL{i}.Text = app.bbObjects{tableRowIndex}.inputs{i}.name;
                set(hL{i},'backgroundcolor', [ 1 1 1 ]);

                thisInput = app.bbObjects{tableRowIndex}.inputs{i};

                switch lower(thisInput.type)
                    case 'string'
                        % create an edit text for text entry
                        hI{i} = uieditfield(hG);
                        hI{i}.Layout.Row = i+2;
                        hI{i}.Layout.Column = 2;
                    case 'numeric'
                        % create an edit text for numeric entry
                        hI{i} = uieditfield(hG, 'numeric');
                        hI{i}.Layout.Row = i+2;
                        hI{i}.Layout.Column = 2;
                    case 'select'
                        % create a drop down list
                        hI{i} = uidropdown(hG);
                        hI{i}.Layout.Row = i+2;
                        hI{i}.Layout.Column = 2;
                        hI{i}.Items = thisInput.options;
                    case 'fromprevious'
                        if workFlowRowIndex==1
                            error('OPENEP/OPENEP_WORKFLOW: This block requires previous input and cannot be the first in a workflows!');
                        else
                            % we are looking for an OUTPUT from the PREVIOUS workflow block which 
                            % has a type (i.e. <OPENEPBUILDINGBLOCK.outputs.type) that matches 
                            % the INPUT from THIS workflow block (.i.e. <OPENEPBUILDINGBLOCK.input.typ)

                            % define the input name we are looking for
                            requiredInputType = thisInput.type;

                            % loop through all the outputs from the previous step to find one that matches
                            numOutputs = numel(app.wfObjects{workFlowRowIndex-1}.outputs);
                            tfOutput = false(numOutputs);
                            for j = 1:numOutputs
                                tempOutputType = app.wfObjects{workFlowRowIndex-1}.outputs{j}.type;
                                if strcmpi(requiredInputType, tempOutputType)
                                    tfOutput(j) = true;
                                end
                            end
                            % check that we have one and only one identified suitable output
                            if sum(tfOutput)<1
                                error('OPENEP/OPENEP_WORKFLOW: No suitable outputs identified from the previously selected workflow block');
                            end
                        end
                        % now we know the index; or indices of the required
                        % output from the previous steps and can put them
                        % in a dropdown
                        hI{i} = uidropdown(hG);
                        hI{i}.Layout.Row = i+2;
                        hI{i}.Layout.Column = 2;
                        hI{i}.Items = app.wfObjects{workFlowRowIndex-1}.outputs{tfOutput}.name;
                end
            end

            % % % IN THIS SECTION WE ADD A NEW BLOCK FOR USE NEXT TIME

            % add a new blank block
            % first we have to identify the CURRENT row number
            rowNumber = hP.Layout.Row;

            % Create an AdditionalBlockPanel
            app.AdditionalBlockPanels{end+1} = uipanel(app.GridLayout2);
            app.AdditionalBlockPanels{end}.Title = 'Block';
            app.AdditionalBlockPanels{end}.BackgroundColor = [0.902 0.902 0.902];
            app.AdditionalBlockPanels{end}.Layout.Row = rowNumber+1;
            app.AdditionalBlockPanels{end}.Layout.Column = 1;

            % Create AdditionalGridLayout
            app.AdditionalGridLayouts{end+1} = uigridlayout(app.AdditionalBlockPanels{end});
            app.AdditionalGridLayouts{end}.ColumnWidth = {'1x', '2x'};
            app.AdditionalGridLayouts{end}.RowHeight = {'fit', 'fit', 'fit', 'fit'};

            % Create Add Button
            app.AdditionalAddButtons{end+1} = uibutton(app.AdditionalGridLayouts{end}, 'push');
            app.AdditionalAddButtons{end}.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AdditionalAddButtons{end}.Layout.Row = 1;
            app.AdditionalAddButtons{end}.Layout.Column = 1;
            app.AdditionalAddButtons{end}.Text = '+';

            % Create Remove Button
            app.AdditionalRemoveButtons{end+1} = uibutton(app.AdditionalGridLayouts{end}, 'push');
            app.AdditionalRemoveButtons{end}.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.AdditionalRemoveButtons{end}.Layout.Row = 2;
            app.AdditionalRemoveButtons{end}.Layout.Column = 1;
            app.AdditionalRemoveButtons{end}.Text = '-';

        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            app.selectedTableRow = indices(1);
        end

        % Button pushed function: Button_2
        function DeleteButtonPushed(app, event)

            % select the correct object to remove from app.wfObjects

            % remove the display; and move all the other grid objects up
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.OpenEPWorkflowEditorUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {481, 481};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {280, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.OpenEPWorkflowEditorUIFigure = uifigure('Visible', 'off');
            app.OpenEPWorkflowEditorUIFigure.Name = 'OpenEP Workflow Editor';
            app.OpenEPWorkflowEditorUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FileMenu = uimenu(app.OpenEPWorkflowEditorUIFigure);
            app.FileMenu.Text = 'File';
            app.LoadWorkflowMenu = uimenu(app.FileMenu);
            app.LoadWorkflowMenu.Text = 'Load Workflow';
            app.SaveWorkflowMenu = uimenu(app.FileMenu);
            app.SaveWorkflowMenu.Text = 'Save Workflow';
            app.ShareMenu = uimenu(app.OpenEPWorkflowEditorUIFigure);
            app.ShareMenu.Text = 'Share';
            app.ZenodoMenu = uimenu(app.ShareMenu);
            app.ZenodoMenu.Text = 'Zenodo';
            app.TwitterMenu = uimenu(app.ShareMenu);
            app.TwitterMenu.Text = 'Twitter';
            app.GridLayout = uigridlayout(app.OpenEPWorkflowEditorUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.UITable = uitable(app.LeftPanel);
            app.UITable.ColumnName = {'Name'; 'Author'};
            app.UITable.RowName = {};
            app.UITable.SelectionType = 'row';
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UITable.Multiselect = 'off';
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'openeplogo.png');
            app.WorkflowsLabel = uilabel(app.LeftPanel);
            app.WorkflowsLabel.Text = 'Workflows';
            app.Image_2 = uiimage(app.LeftPanel);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'arrow.png');
            app.RightPanel = uipanel(app.GridLayout);
            app.Panel = uipanel(app.RightPanel);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.BlockPanel = uipanel(app.GridLayout2);
            app.BlockPanel.Title = 'Block';
            app.GridLayout3 = uigridlayout(app.BlockPanel);
            app.Button = uibutton(app.GridLayout3, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.Button.Text = '+';
            app.Button_2 = uibutton(app.GridLayout3, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.Button_2.Text = '-';
            % Show the figure after all components are created
            app.OpenEPWorkflowEditorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = openep_workflow

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OpenEPWorkflowEditorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OpenEPWorkflowEditorUIFigure)
        end
    end
end